classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EvaluationMetricsTextArea       matlab.ui.control.TextArea
        EvaluationMetricsTextAreaLabel  matlab.ui.control.Label
        RecordingLamp                   matlab.ui.control.Lamp
        RecordingLampLabel              matlab.ui.control.Label
        VoiceClassificationSystemLabel  matlab.ui.control.Label
        ClassifyButton                  matlab.ui.control.Button
        RecordButton                    matlab.ui.control.Button
        InitializeButton                matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        d
        dInfo
        recorded
        data
        Model
        prediction
             
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function initDAQ(app, event)
            adi=daq("adi");                  %create DAQ object and connection to DAQ
            addinput(adi,'smu1','a','Voltage');
            adi.ScansAvailableFcnCount=5*adi.Rate;      %# samples to scan before callback
            app.d=adi;              %store DAQ object to app property d
            app.dInfo=adi.Channels.Device;  %store DAQ properties to app property dInfo
            disp('DAQ initialized')
            
            app.Model = load('Model_mean.mat');
            
        end

        % Button pushed function: RecordButton
        function Record(app, event)
            fs=app.d.Rate;
            app.RecordingLamp.Color='red';
            pause(0.01);
            disp('Recording Started')
            app.data=read(app.d,5*fs,'OutputFormat','Matrix'); 
            app.RecordingLamp.Color='green';
            disp('Recording Ended')
            
            plot(app.UIAxes,app.data/mean(app.data),'k')
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            mfcc_coeff_test = mfcc(app.data/mean(app.data),app.d.Rate);
            %gtcc_coeff_test = gtcc(app.data,app.d.Rate);
            
            test = reshape([mfcc_coeff_test],[],1);
            %test = reshape([mfcc_coeff_test;gtcc_coeff_test],[],1);
            app.prediction = predict(app.Model.Model,test');
            if app.prediction == 0 
                disp('Statement')
            else
                disp('Question')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Voltage vs Time for Input Speech')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @initDAQ, true);
            app.InitializeButton.Text = 'Initialize';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @Record, true);
            app.RecordButton.Text = 'Record';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.VoiceClassificationSystemLabel = uilabel(app.UIFigure);
            app.VoiceClassificationSystemLabel.Text = 'Voice Classification System';
            app.RecordingLampLabel = uilabel(app.UIFigure);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.UIFigure);
            app.EvaluationMetricsTextAreaLabel = uilabel(app.UIFigure);
            app.EvaluationMetricsTextAreaLabel.Text = 'Evaluation Metrics ';
            app.EvaluationMetricsTextArea = uitextarea(app.UIFigure);
            app.EvaluationMetricsTextArea.Value = {'Accuracy = 73.81%'; 'Precision = 71.43%'; 'Recall = 75.00%'; 'F1 Score = 73.17%'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end